classdef Step_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        Path_FilePanel                matlab.ui.container.Panel
        Step_SavePathTextArea         matlab.ui.control.TextArea
        Step_SavePathTextAreaLabel    matlab.ui.control.Label
        Signal_SavePathTextArea       matlab.ui.control.TextArea
        Signal_SavePathTextAreaLabel  matlab.ui.control.Label
        File_LoadPathTextArea         matlab.ui.control.TextArea
        File_LoadPathTextAreaLabel    matlab.ui.control.Label
        FunctionPanel                 matlab.ui.container.Panel
        Delete_stepButton             matlab.ui.control.Button
        Add_stepButton                matlab.ui.control.Button
        Data_SaveButton               matlab.ui.control.Button
        Manual_DetectionButton        matlab.ui.control.Button
        subButton                     matlab.ui.control.Button
        addButton                     matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        Path_OKButton                 matlab.ui.control.Button
        FigurePanel                   matlab.ui.container.Panel
        UIAxes1                       matlab.ui.control.UIAxes
    end

%--------------------------------------------------------------------------
% Step Detection from Trunk IMU Data
% Author: Jiahui Wang, PhD Candidate, KU Leuven
% First created: 2023-10-14
%
% If you use this app, please cite:
% Wang J, Sergooris A, Corten K, Timmermans AAA, Vanwanseele B.
% Evaluating Gait Quality in People with Hip Osteoarthritis During Habitual
% and Fast Walking Using a Trunk Inertial Measurement Unit in Clinical Settings.
% Sensors. 2026;26(3):820.
% https://pmc.ncbi.nlm.nih.gov/articles/PMC12899795/
%--------------------------------------------------------------------------  
    properties (Access = private)
        Para_Data % Description
    end
    
    methods (Access = private)
        
        function func_draw(app,Stride_pos)
            Pace_Tilt_convert  = app.Para_Data.Data_Origin{app.Para_Data.Para.Idx_MAT};
            Data_ipt  = table2array(Pace_Tilt_convert);
            Aantpost   = -Data_ipt(:,2);  
            NumCal = app.Para_Data.Para.Num_MAT;
            IdxCal = app.Para_Data.Para.Idx_MAT;
            Aantpost = Aantpost/max(abs(Aantpost));
            acorr_ap = xcorr(Aantpost, 'unbiased');
            zerophase_i = length(Aantpost);
            acorr_ap_norm = acorr_ap./acorr_ap(zerophase_i);
            [~,peakLoc_norm] = findpeaks(acorr_ap_norm,'MinPeakHeight',0.2, 'MinPeakDistance', 85);
            peakLoc_norm = peakLoc_norm(2:end-1);
            pos_temp = find(zerophase_i == peakLoc_norm);
            if (nargin==1)
                Stride_pos = peakLoc_norm(pos_temp:end) - peakLoc_norm(pos_temp) + 1;
                Stride_pos = Stride_pos + 10;
                for i_pos = 1:length(Stride_pos)
                    min_pos = max(Stride_pos(i_pos) - 20,1);
                    max_pos = min(Stride_pos(i_pos) + 40,length(Aantpost));
                    [~,new_pos] = min( Aantpost(min_pos:max_pos) );
                    Stride_pos(i_pos) = min_pos + new_pos -1;
                end
            end
            cla(app.UIAxes1)
            plot(app.UIAxes1,-Aantpost)
            app.UIAxes1.Title.String = ['APacceleration(Num:',num2str(NumCal),',Idx:',num2str(IdxCal),')'];
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,Stride_pos,-Aantpost(Stride_pos),'ro'); 

            app.Para_Data.Path.Stride_pos{app.Para_Data.Para.Idx_MAT} = Stride_pos;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Find_StridePos';
            app.Para_Data.Path.FilePath = [];
            app.Para_Data.Path.SavePath = [];
            app.Para_Data.Para.pos_cut  = [];
            app.Para_Data.Para.Idx_MAT = 1;
        end

        % Button pushed function: Path_OKButton
        function Path_OKButtonPushed(app, event)
            app.Para_Data.Path.FilePath = app.File_LoadPathTextArea.Value{1};
            app.Para_Data.Path.DataSavePath = app.Signal_SavePathTextArea.Value{1};
            app.Para_Data.Path.PosSavePath = app.Step_SavePathTextArea.Value{1};
            FilePath = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath))
                app.Para_Data.Path.Mat_Path = [];
                Mat_Path = dir(strcat(FilePath,'\*.mat'));
                app.Para_Data.Para.Num_MAT = length(Mat_Path);
                for idx = 1:length(Mat_Path)
                    FileName{idx} = Mat_Path(idx).name;
                    Data_Table{idx,1} = idx;
                    Data_Table{idx,2} = FileName{idx};
                    app.Para_Data.Path.Mat_Path{idx} = strcat(FilePath,'\',FileName{idx});
                    app.Para_Data.Path.Mat_Name{idx} = FileName{idx};
                    load(app.Para_Data.Path.Mat_Path{idx});
                    app.Para_Data.Data_Origin{idx} = Pace_Tilt_convert; 
                    if (mod(idx,2)==1)
                        BackgroundColor(idx,:) = [1,1,1];
                    else
                        BackgroundColor(idx,:) = [0.9,0.9,0.9];
                    end
                end
                app.Para_Data.Para.BackgroundColor = BackgroundColor;
                app.UITable.Data = Data_Table;
                app.UITable.BackgroundColor = BackgroundColor;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            FilePath = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    app.UITable.BackgroundColor = app.Para_Data.Para.BackgroundColor;
                    app.UITable.BackgroundColor(app.Para_Data.Para.Idx_MAT,:) = [0.6,0.7,0.8];
                    func_draw(app);
                end
            end
        end

        % Button pushed function: subButton
        function subButtonPushed(app, event)
            FilePath = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    app.Para_Data.Para.Idx_MAT = app.Para_Data.Para.Idx_MAT-1;
                    if ( app.Para_Data.Para.Idx_MAT < 1)
                        app.Para_Data.Para.Idx_MAT = app.Para_Data.Para.Num_MAT;
                    end
                    app.UITable.BackgroundColor = app.Para_Data.Para.BackgroundColor;
                    app.UITable.BackgroundColor(app.Para_Data.Para.Idx_MAT,:) = [0.6,0.7,0.8];
                    func_draw(app);
                end
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            FilePath = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    app.Para_Data.Para.Idx_MAT = app.Para_Data.Para.Idx_MAT+1;
                    if ( app.Para_Data.Para.Idx_MAT > app.Para_Data.Para.Num_MAT)
                        app.Para_Data.Para.Idx_MAT = 1;
                    end
                    app.UITable.BackgroundColor = app.Para_Data.Para.BackgroundColor;
                    app.UITable.BackgroundColor(app.Para_Data.Para.Idx_MAT,:) = [0.6,0.7,0.8];
                    func_draw(app);
                end
            end     
        end

        % Button pushed function: Manual_DetectionButton
        function Manual_DetectionButtonPushed(app, event)
            FilePath     = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            PosSavePath  = app.Para_Data.Path.PosSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath)&&~isempty(PosSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    cursor_info = [];
                    for i = 1:length(app.UIAxes1.Children)
                        cursor_info = [cursor_info;app.UIAxes1.Children(i).Children];
                    end
                    if (~isempty(cursor_info) )
                        for idx_mabual = 1:length(cursor_info)
                            Stride_pos(idx_mabual) = cursor_info(idx_mabual).X; 
                        end
                        Stride_pos = sort(unique(Stride_pos));
                        func_draw(app,Stride_pos);
                    end
                end
            end
            
        end

        % Button pushed function: Add_stepButton
        function Add_stepButtonPushed(app, event)
            FilePath     = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            PosSavePath  = app.Para_Data.Path.PosSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath)&&~isempty(PosSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    Len = length(app.UIAxes1.Children);
                    Stride_pos_all = [];
                    cursor_info_add = [];
                    if( Len==1 )
                        cursor_info_add  = app.UIAxes1.Children(1).Children;
                    elseif( Len==2 )
                        cursor_info_add  = app.UIAxes1.Children(2).Children;
                        Stride_pos_all  = app.UIAxes1.Children(1).XData;
                    end
                    if (~isempty(cursor_info_add) )
                        for idx_mabual = 1:length(cursor_info_add)
                            Stride_pos_add(idx_mabual) = cursor_info_add(idx_mabual).X; 
                        end
                        Stride_pos   = sort(unique([Stride_pos_all,Stride_pos_add]));
                        func_draw(app,Stride_pos);
                    end

                end
            end
                          
        end

        % Button pushed function: Delete_stepButton
        function Delete_stepButtonPushed(app, event)
            FilePath     = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            PosSavePath  = app.Para_Data.Path.PosSavePath;
            if (~isempty(FilePath)&&~isempty(DataSavePath)&&~isempty(PosSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    Len = length(app.UIAxes1.Children);
                    Stride_pos_all = [];
                    cursor_info_delect = [];
                    if( Len==2 )
                        cursor_info_delect  = app.UIAxes1.Children(1).Children;
                        Stride_pos_all  = app.UIAxes1.Children(1).XData;
                    end

                    if (~isempty(cursor_info_delect) )
                        for idx_mabual = 1:length(cursor_info_delect)
                            Stride_pos_delect(idx_mabual) = cursor_info_delect(idx_mabual).X; 
                        end
                        Stride_pos_delect   = sort(unique(Stride_pos_delect));
                        Stride_pos = setdiff(Stride_pos_all,Stride_pos_delect);
                        func_draw(app,Stride_pos);
                    end

                end
            end
                    
        end

        % Button pushed function: Data_SaveButton
        function Data_SaveButtonPushed(app, event)
            FilePath     = app.Para_Data.Path.FilePath;
            DataSavePath = app.Para_Data.Path.DataSavePath;
            PosSavePath  = app.Para_Data.Path.PosSavePath;
            IdxCal = app.Para_Data.Para.Idx_MAT;
            if (~isempty(FilePath)&&~isempty(DataSavePath)&&~isempty(PosSavePath))
                if (~isempty(app.Para_Data.Path.Mat_Path))
                    if (~isempty(app.Para_Data.Path.Stride_pos{IdxCal}))
                        Filepara.FileName = app.Para_Data.Path.Mat_Name{IdxCal};
                        Filepara.FilePath_new_data = [DataSavePath,'\'];
                        Filepara.FilePath_new_stride = [PosSavePath,'\'];
                        Pace_Tilt_convert  = app.Para_Data.Data_Origin{IdxCal}; 
                        Data_ipt  = table2array(Pace_Tilt_convert);
                        Stride_pos = app.Para_Data.Path.Stride_pos{IdxCal};
                        func_signal_truncating(Data_ipt,Stride_pos,Filepara)  
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FigurePanel = uipanel(app.UIFigure);
            app.FigurePanel.Title = 'Figure';
            app.UIAxes1 = uiaxes(app.FigurePanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'Point')
            ylabel(app.UIAxes1, 'Amp')
            zlabel(app.UIAxes1, 'Z')
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Title = 'Function';
            app.Path_OKButton = uibutton(app.FunctionPanel, 'push');
            app.Path_OKButton.ButtonPushedFcn = createCallbackFcn(app, @Path_OKButtonPushed, true);
            app.Path_OKButton.Text = 'Path_OK';
            app.PlotButton = uibutton(app.FunctionPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.addButton = uibutton(app.FunctionPanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '+';
            app.subButton = uibutton(app.FunctionPanel, 'push');
            app.subButton.ButtonPushedFcn = createCallbackFcn(app, @subButtonPushed, true);
            app.subButton.Text = '-';
            app.Manual_DetectionButton = uibutton(app.FunctionPanel, 'push');
            app.Manual_DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @Manual_DetectionButtonPushed, true);
            app.Manual_DetectionButton.Text = 'Manual_Detection';
            app.Data_SaveButton = uibutton(app.FunctionPanel, 'push');
            app.Data_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Data_SaveButtonPushed, true);
            app.Data_SaveButton.Text = 'Data_Save';
            app.Add_stepButton = uibutton(app.FunctionPanel, 'push');
            app.Add_stepButton.ButtonPushedFcn = createCallbackFcn(app, @Add_stepButtonPushed, true);
            app.Add_stepButton.Text = 'Add_step';
            app.Delete_stepButton = uibutton(app.FunctionPanel, 'push');
            app.Delete_stepButton.ButtonPushedFcn = createCallbackFcn(app, @Delete_stepButtonPushed, true);
            app.Delete_stepButton.Text = 'Delete_step';
            app.Path_FilePanel = uipanel(app.UIFigure);
            app.Path_FilePanel.Title = 'Path_File';
            app.File_LoadPathTextAreaLabel = uilabel(app.Path_FilePanel);
            app.File_LoadPathTextAreaLabel.Text = 'File_LoadPath';
            app.File_LoadPathTextArea = uitextarea(app.Path_FilePanel);
            app.Signal_SavePathTextAreaLabel = uilabel(app.Path_FilePanel);
            app.Signal_SavePathTextAreaLabel.Text = 'Signal_SavePath';
            app.Signal_SavePathTextArea = uitextarea(app.Path_FilePanel);
            app.Step_SavePathTextAreaLabel = uilabel(app.Path_FilePanel);
            app.Step_SavePathTextAreaLabel.Text = 'Step_SavePath';
            app.Step_SavePathTextArea = uitextarea(app.Path_FilePanel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Idx'; 'MATFile'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Step_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end